classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ParametersLabel                matlab.ui.control.Label
        AnnotationfilesLabel           matlab.ui.control.Label
        Annotationfilesrater1Button    matlab.ui.control.Button
        Annotationfilesrater2Button    matlab.ui.control.Button
        AnnotationoutputfolderButton   matlab.ui.control.Button
        OutputLabel                    matlab.ui.control.Label
        agreementtableButton           matlab.ui.control.Button
        RUNButton                      matlab.ui.control.Button
        calculatefinalagreementButton  matlab.ui.control.Button
        correctionDropDownLabel        matlab.ui.control.Label
        correctionDropDown             matlab.ui.control.DropDown
        toleranceinsEditFieldLabel     matlab.ui.control.Label
        toleranceinsEditField          matlab.ui.control.EditField
        savefiguresCheckBox            matlab.ui.control.CheckBox
        RightPanel                     matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

  
  properties (Access = private)
    tolerance
    correction
    files
    path
    folder_combined
    agreement_table
    save_fig
  end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {461, 461};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {245, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
      clc;
      diary log.txt
      % checks
      if isempty(app.tolerance)
          uialert(app.UIFigure, 'Please choose a value for the tolerance parameter', 'No tolerance value found')
          return
      elseif ~(strcmp(app.correction, 'include') | strcmp(app.correction, 'exclude'))
          uialert(app.UIFigure, 'Please choose a value for the correction parameter', 'No correction value found')
          return
      elseif isempty(app.files) 
          uialert(app.UIFigure, 'Please select annotation files', 'No annotations files found')
          return
      elseif isempty(app.files{1})
          uialert(app.UIFigure, 'Please select annotation files for rater 1', 'No annotations files found for rater 1')
          return
      elseif length(app.files)<2
          uialert(app.UIFigure, 'Please select annotation files for rater 2', 'No annotations files found for rater 2')
          return
      elseif isempty(app.folder_combined)
          uialert(app.UIFigure, 'Please select a folder to save your output', 'No output folder found')
          return
      elseif isempty(app.agreement_table)
          uialert(app.UIFigure, 'Please select an agreement table', 'No agreement table found')
          return
      end
      files = app.files;
      path = app.path;
      if length(files{1})~= length(files{2})
          uialert(app.UIFigure, 'Not the same number of annotation files (.txt) selected for both raters', 'error with annotation files') 
      else
        % sort names in ascending order so order of files for the two rater
        % correspond (make sure they have similar names though!)
        for i=1:2
          [~, idx]=sort(files{i});
          files{i}=files{i}(idx);
        end
      end
      
      % loop over files
      for f=1:length(files{1})
        for i=1:2
          file_parts{i} = strsplit(files{i}{f}, '_');
          sub{i} = file_parts{i}{1};
          middle{i} = strjoin(file_parts{i}(2:end-1), '_');
          annotator{i} = file_parts{i}{end};
          full_file{i} = fullfile(path{i}, files{i}{f});
        end
        % checks
        if ~strcmp(sub{1},sub{2}) | ~strcmp(middle{1},middle{2})
          error('file names are not similar: \n %s \n %s', files{1}(f).name, files{2}(f).name)
        elseif strcmp(annotator{1},annotator{2})
          error('files are annotated by the same rater: %s and %s', annotator{1}, annotators{2})
        end
        
        if isempty(middle{1})
          name_combined = sub{1};
        else
          name_combined = strcat(sub{1}, '_', middle{1});
        end
        fprintf('<strong>%s</strong>\n', name_combined)
        file_combined=fullfile(app.folder_combined, name_combined);
        [agreement_info] = combine_FOGannotations(full_file{1}, full_file{2}, file_combined, app.agreement_table, sub{1}, app.correction, app.tolerance, 0, app.save_fig);
%         agreement_info{:,3:7} = round(agreement_info{:,3:7},2); % round values
%         agreement_info{:,8:end} = round(agreement_info{:,8:end});
        n = size(app.UITable.Data, 1);
        if n==0
          app.UITable.Data = agreement_info(:,2:end);
        else
          app.UITable.Data = [app.UITable.Data; agreement_info(:,2:end)];
        end
      end
      diary off
      commandwindow();
        end

        % Button pushed function: Annotationfilesrater1Button
        function Annotationfilesrater1ButtonPushed(app, event)
      [app.files{1}, app.path{1}] = uigetfile('*.txt', 'MultiSelect', 'on');
      cd(fullfile(app.path{1}, '\..'))
        end

        % Button pushed function: Annotationfilesrater2Button
        function Annotationfilesrater2ButtonPushed(app, event)
      [app.files{2}, app.path{2}] = uigetfile('*.txt', 'MultiSelect', 'on');
      cd(fullfile(app.path{2}, '\..'))
        end

        % Button pushed function: AnnotationoutputfolderButton
        function AnnotationoutputfolderButtonPushed(app, event)
      app.folder_combined = uigetdir();
      cd(fullfile(app.folder_combined, '\..'))
        end

        % Button pushed function: agreementtableButton
        function agreementtableButtonPushed(app, event)
      answer = uiconfirm(app.UIFigure, 'Do you want to use an existing agreement table or create a new one?', 'agreement table', 'Options', {'Use existing', 'Create new'});
      switch answer
        case 'Create new'
          [agreement_table_file, agreement_table_folder] = uiputfile('agreement_table.tsv');
        case 'Use existing'
          [agreement_table_file, agreement_table_folder] = uigetfile('agreement_table.tsv');
      end
      app.agreement_table = fullfile(agreement_table_folder, agreement_table_file);
        end

        % Value changed function: correctionDropDown
        function correctionDropDownValueChanged(app, event)
      app.correction = app.correctionDropDown.Value;
        end

        % Value changed function: toleranceinsEditField
        function toleranceinsEditFieldValueChanged(app, event)
      value = app.toleranceinsEditField.Value;
      app.tolerance = str2num(value);
        end

        % Button pushed function: calculatefinalagreementButton
        function calculatefinalagreementButtonPushed(app, event)
            agr_table = readtable(app.agreement_table, 'FileType', 'text', 'Delimiter', '\t');
            agreement = agreement_calculator(agr_table); % calculate agreement
            m = size(agr_table, 2);
            % create row containing total agreement
            last_row = table('Size', [1 m], 'VariableTypes', ['cell' 'cell' repmat({'double'}, 1, 16)], 'VariableNames', agr_table.Properties.VariableNames);
            last_row.filename = {'TOTAL'};
            last_row.positive_agreement = agreement.pos_agree;
            last_row.negative_agreement = agreement.neg_agree;
            last_row.prevalence_index= agreement.prev_indx;
            w = agr_table.duration_FOG_agreed/sum(agr_table.duration_FOG_agreed); % attribute weigths according to the total duration of agreed FOG
            last_row.agreement_trigger = nansum(w.*agr_table.agreement_trigger);
            last_row.agreement_type = nansum(w.*agr_table.agreement_type);
            last_row{end,8:end} = sum(agr_table{:,8:end});
            % show table
            final_table = [agr_table(:,2:end); last_row(:,2:end)];
            app.UITable.Data = final_table;
            % save new table
            writetable(final_table, app.agreement_table,  'FileType', 'text', 'Delimiter', '\t'); % as tsv
            writetable(final_table, app.agreement_table, 'FileType', 'spreadsheet'); % as spread sheet

        end

        % Value changed function: savefiguresCheckBox
        function savefiguresCheckBoxValueChanged(app, event)
            app.save_fig = app.savefiguresCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.AnnotationfilesLabel = uilabel(app.LeftPanel);
            app.AnnotationfilesLabel.Text = 'Annotation files';
            app.Annotationfilesrater1Button = uibutton(app.LeftPanel, 'push');
            app.Annotationfilesrater1Button.ButtonPushedFcn = createCallbackFcn(app, @Annotationfilesrater1ButtonPushed, true);
            app.Annotationfilesrater1Button.Text = 'Annotation files rater 1';
            app.Annotationfilesrater2Button = uibutton(app.LeftPanel, 'push');
            app.Annotationfilesrater2Button.ButtonPushedFcn = createCallbackFcn(app, @Annotationfilesrater2ButtonPushed, true);
            app.Annotationfilesrater2Button.Text = 'Annotation files rater 2';
            app.AnnotationoutputfolderButton = uibutton(app.LeftPanel, 'push');
            app.AnnotationoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotationoutputfolderButtonPushed, true);
            app.AnnotationoutputfolderButton.Text = 'Annotation output folder';
            app.OutputLabel = uilabel(app.LeftPanel);
            app.OutputLabel.Text = 'Output';
            app.agreementtableButton = uibutton(app.LeftPanel, 'push');
            app.agreementtableButton.ButtonPushedFcn = createCallbackFcn(app, @agreementtableButtonPushed, true);
            app.agreementtableButton.Text = 'agreement table';
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.calculatefinalagreementButton = uibutton(app.LeftPanel, 'push');
            app.calculatefinalagreementButton.ButtonPushedFcn = createCallbackFcn(app, @calculatefinalagreementButtonPushed, true);
            app.calculatefinalagreementButton.Text = 'calculate final agreement';
            app.correctionDropDownLabel = uilabel(app.LeftPanel);
            app.correctionDropDownLabel.Text = 'correction';
            app.correctionDropDown = uidropdown(app.LeftPanel);
            app.correctionDropDown.Items = {'choose', 'include', 'exclude'};
            app.correctionDropDown.ValueChangedFcn = createCallbackFcn(app, @correctionDropDownValueChanged, true);
            app.correctionDropDown.Value = 'choose';
            app.toleranceinsEditFieldLabel = uilabel(app.LeftPanel);
            app.toleranceinsEditFieldLabel.Text = 'tolerance (in s)';
            app.toleranceinsEditField = uieditfield(app.LeftPanel, 'text');
            app.toleranceinsEditField.ValueChangedFcn = createCallbackFcn(app, @toleranceinsEditFieldValueChanged, true);
            app.savefiguresCheckBox = uicheckbox(app.LeftPanel);
            app.savefiguresCheckBox.ValueChangedFcn = createCallbackFcn(app, @savefiguresCheckBoxValueChanged, true);
            app.savefiguresCheckBox.Text = 'save figures';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'filename'; 'positive agreement'; 'negative agreement'; 'prevalence index'; 'agreement trigger'; 'agreement type'; 'number FOG rater 1'; 'number FOG rater 2'; 'number FOG agreed'; 'number FOG disagreed rater 1'; 'number FOG disagreed rater 2'; 'duration FOG rater 1'; 'duration FOG rater 2'; 'duration FOG agreed'; 'duration FOG disagreed rater 1'; 'duration FOG disagreed rater 2'; 'total duration'};
            app.UITable.RowName = {''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end