classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FOGtool                        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        AnnotationfilesLabel           matlab.ui.control.Label
        Annotationfilesrater1Button    matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        Annotationfilesrater2Button    matlab.ui.control.Button
        TextArea_2                     matlab.ui.control.TextArea
        ParametersLabel                matlab.ui.control.Label
        CorrectionDropDownLabel        matlab.ui.control.Label
        CorrectionDropDown             matlab.ui.control.DropDown
        ToleranceinsEditFieldLabel     matlab.ui.control.Label
        ToleranceinsEditField          matlab.ui.control.EditField
        AnnotationoutputfolderButton   matlab.ui.control.Button
        OutputLabel                    matlab.ui.control.Label
        AgreementtableButton           matlab.ui.control.Button
        SavefiguresCheckBox            matlab.ui.control.CheckBox
        RUNButton                      matlab.ui.control.Button
        CalculatefinalagreementButton  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        Image                          matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        AgreementtableLabel            matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

  
  properties (Access = private)
    tolerance
    correction
    files
    path
    folder_combined
    agreement_table
    save_fig
  end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     % add default values
      app.tolerance = 2;
      app.correction = 'include';
      app.files = cell(2,1);
      app.path = cell(2,1);
      
      % add tooltips
      app.Annotationfilesrater1Button.Tooltip = 'Please, select the annotation files of rater 1.';
      app.Annotationfilesrater2Button.Tooltip = 'Please, select the annotation files of rater 2.';
      app.ToleranceinsEditField.Tooltip = 'Not-overlapping annotations that are longer than the tolerance will be marked as "to discuss".';
      app.CorrectionDropDown.Tooltip = 'Not-overlapping annotations that are shorter than the tolerance will be included/excluded as FOG based on the correction parameter.';
      app.AnnotationoutputfolderButton.Tooltip = 'Please, select the folder in which the output files should be saved.';
      app.AgreementtableButton.Tooltip = 'Please, select the agreement table which should be used to calculate the overall agreement of all files of the project.';
      app.RUNButton.Tooltip = 'Run the algorithm for the selected files.';
      app.CalculatefinalagreementButton.Tooltip = 'Calculate the overall agreement of all files included in the agreement table.';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.FOGtool.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {662, 662};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
      clc;
      diary log.txt
      % checks
      if isempty(app.tolerance)
        uialert(app.FOGtool, 'Please choose a value for the tolerance parameter', 'No tolerance value found')
        return
      elseif ~(strcmp(app.correction, 'include') | strcmp(app.correction, 'exclude'))
        uialert(app.FOGtool, 'Please choose a value for the correction parameter', 'No correction value found')
        return
      elseif isempty(app.files{1})
        uialert(app.FOGtool, 'Please select annotation files for rater 1', 'No annotations files found for rater 1')
        return
      elseif isempty(app.files{2})
        uialert(app.FOGtool, 'Please select annotation files for rater 2', 'No annotations files found for rater 2')
        return
      elseif isempty(app.folder_combined)
        uialert(app.FOGtool, 'Please select a folder to save your output', 'No output folder found')
        return
      elseif isempty(app.agreement_table)
        uialert(app.FOGtool, 'Please select an agreement table', 'No agreement table found')
        return
      end
      files = app.files;
      path = app.path;
      if ischar(files{1}) & ischar(files{2}) % one annotation file selected for both raters
        files{1} = files(1);
        files{2} = files(2);
      elseif ischar(files{1}) | ischar(files{2}) % only one annotation file selected for one of the raters
        uialert(app.FOGtool, 'Not the same number of annotation files (.txt) selected for both raters', 'error with annotation files')
        return
      elseif length(files{1})~= length(files{2}) % not the same number of annotation files selected for both raters
        uialert(app.FOGtool, 'Not the same number of annotation files (.txt) selected for both raters', 'error with annotation files')
        return
      end
      
      % sort names in ascending order so order of files for the two rater
      % correspond (make sure they have similar names though!)
      for i=1:2
        [~, idx]=sort(files{i});
        files{i}=files{i}(idx);
      end
      
      % loop over files
      for f=1:length(files{1})
          % plot progress
          d = uiprogressdlg(app.FOGtool, 'Value', f/(length(files{1})+1) , "Message", 'Processing...', 'Title', 'Progress status');
        for i=1:2
          file_parts{i} = strsplit(files{i}{f}, {'_', '.'});
          sub{i} = file_parts{i}{1};
          middle{i} = strjoin(file_parts{i}(2:end-2), '_');
          annotator{i} = file_parts{i}{end-1};
          full_file{i} = fullfile(path{i}, files{i}{f});
        end
        % checks
        if ~strcmp(sub{1},sub{2}) | ~strcmp(middle{1},middle{2})
          uialert(app.FOGtool, sprintf('file names are not similar: \n %s \n %s', files{1}{f}, files{2}{f}), 'annotation files not similar')
          return
        elseif strcmp(annotator{1},annotator{2})
          uialert(app.FOGtool, sprintf('files are annotated by the same rater: %s and %s', annotator{1}, annotator{2}), 'similar raters')
          return
        end
        
        if isempty(middle{1})
          name_combined = sub{1};
        else
          name_combined = strcat(sub{1}, '_', middle{1});
        end
        fprintf('<strong>%s</strong>\n', name_combined)
        file_combined=fullfile(app.folder_combined, name_combined);
        [agreement_info] = combine_FOGannotations(full_file{1}, full_file{2}, file_combined, app.agreement_table, sub{1}, app.correction, app.tolerance, 0, app.save_fig);
        
        % For display purposes
        agreement_info_round = cellfun(@(x) num2str(x, '%.2f'),...
          table2cell(agreement_info(:,[3:7, 13:18])), 'UniformOutput',false);
        agreement_info_disp = [agreement_info(:,1:2), cell2table(agreement_info_round(:,1:5), 'VariableNames',agreement_info.Properties.VariableNames(3:7)),...
          agreement_info(:,8:12), cell2table(agreement_info_round(:,6:end),'VariableNames',agreement_info.Properties.VariableNames(13:end))];
        
        n = size(app.UITable.Data, 1);
        if n==0
          app.UITable.Data = agreement_info_disp(:,2:end);
        else
          idx = find(strcmp(name_combined, app.UITable.Data.filename)); % check if filename is already present in table
          if idx
            app.UITable.Data(idx,:) = agreement_info_disp(:,2:end);
          else
            app.UITable.Data = [app.UITable.Data; agreement_info_disp(:,2:end)];
          end
        end
      end
      diary off

        end

        % Button pushed function: Annotationfilesrater1Button
        function Annotationfilesrater1ButtonPushed(app, event)
      [app.files{1}, app.path{1}] = uigetfile('*.txt', 'MultiSelect', 'on');
      try
        app.TextArea.Value = app.files{1};
      end
      cd(fullfile(app.path{1}, '\..'))
      figure(app.FOGtool)
        end

        % Button pushed function: Annotationfilesrater2Button
        function Annotationfilesrater2ButtonPushed(app, event)
      [app.files{2}, app.path{2}] = uigetfile('*.txt', 'MultiSelect', 'on');
      try
        app.TextArea_2.Value = app.files{2};
      end
      cd(fullfile(app.path{2}, '\..'))
      figure(app.FOGtool)
        end

        % Button pushed function: AnnotationoutputfolderButton
        function AnnotationoutputfolderButtonPushed(app, event)
      app.folder_combined = uigetdir();
      cd(fullfile(app.folder_combined, '\..'))
      figure(app.FOGtool)
        end

        % Button pushed function: AgreementtableButton
        function AgreementtableButtonPushed(app, event)
      answer = uiconfirm(app.FOGtool, 'Do you want to use an existing agreement table or create a new one?', 'agreement table', 'Options', {'Use existing', 'Create new'});
      switch answer
        case 'Create new'
          [agreement_table_file, agreement_table_folder] = uiputfile('agreement_table.tsv');
        case 'Use existing'
          [agreement_table_file, agreement_table_folder] = uigetfile('agreement_table.tsv');
      end
      app.agreement_table = fullfile(agreement_table_folder, agreement_table_file);
      figure(app.FOGtool)
        end

        % Value changed function: CorrectionDropDown
        function CorrectionDropDownValueChanged(app, event)
      app.correction = app.CorrectionDropDown.Value;
      figure(app.FOGtool)
        end

        % Value changed function: ToleranceinsEditField
        function ToleranceinsEditFieldValueChanged(app, event)
      value = app.ToleranceinsEditField.Value;
      app.tolerance = str2num(value);
      figure(app.FOGtool)
        end

        % Button pushed function: CalculatefinalagreementButton
        function CalculatefinalagreementButtonPushed(app, event)
      agr_table = readtable(app.agreement_table, 'FileType', 'text', 'Delimiter', '\t');
      agr_table = agr_table(~strcmp(agr_table.filename, 'TOTAL'),:); % remove the TOTAL row if already present
      agreement = agreement_calculator(agr_table); % calculate agreement
      m = size(agr_table, 2);
      
      % create new row containing total agreement
      last_row = table('Size', [1 m], 'VariableTypes', ['cell' 'cell' repmat({'double'}, 1, 16)], 'VariableNames', agr_table.Properties.VariableNames);
      last_row.subject = {'TOTAL'};
      last_row.filename = {'TOTAL'};
      last_row.positive_agreement = agreement.pos_agree;
      last_row.negative_agreement = agreement.neg_agree;
      last_row.prevalence_index= agreement.prev_indx;
      w = agr_table.duration_FOG_agreed/sum(agr_table.duration_FOG_agreed); % attribute weigths according to the total duration of agreed FOG
      last_row.agreement_trigger = nansum(w.*agr_table.agreement_trigger);
      last_row.agreement_type = nansum(w.*agr_table.agreement_type);
      last_row{end,8:end} = sum(agr_table{:,8:end});
      
      % show table
      final_table = [agr_table; last_row];
      % For display purposes
      final_table_round = cellfun(@(x) num2str(x, '%.2f'),...
        table2cell(final_table(:,[3:7, 13:18])), 'UniformOutput',false);
      final_table_disp = [final_table(:,1:2), cell2table(final_table_round(:,1:5), 'VariableNames',final_table.Properties.VariableNames(3:7)),...
        final_table(:,8:12), cell2table(final_table_round(:,6:end),'VariableNames',final_table.Properties.VariableNames(13:end))];
      app.UITable.Data = final_table_disp(:,2:end);
      
      % save new table
      [path, name, ext] = fileparts(app.agreement_table);
      writetable(final_table, app.agreement_table,  'FileType', 'text', 'Delimiter', '\t'); % as tsv
      writetable(final_table, fullfile(path, [name, '.xlsx']), 'FileType', 'spreadsheet'); % as spread sheet
        end

        % Value changed function: SavefiguresCheckBox
        function SavefiguresCheckBoxValueChanged(app, event)
      app.save_fig = app.SavefiguresCheckBox.Value;
      figure(app.FOGtool)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FOGtool = uifigure('Visible', 'off');
            app.FOGtool.Name = 'UI Figure';
            app.FOGtool.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.FOGtool);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AnnotationfilesLabel = uilabel(app.LeftPanel);
            app.AnnotationfilesLabel.Text = 'Annotation files';
            app.Annotationfilesrater1Button = uibutton(app.LeftPanel, 'push');
            app.Annotationfilesrater1Button.ButtonPushedFcn = createCallbackFcn(app, @Annotationfilesrater1ButtonPushed, true);
            app.Annotationfilesrater1Button.Text = 'Annotation files rater 1';
            app.TextArea = uitextarea(app.LeftPanel);
            app.Annotationfilesrater2Button = uibutton(app.LeftPanel, 'push');
            app.Annotationfilesrater2Button.ButtonPushedFcn = createCallbackFcn(app, @Annotationfilesrater2ButtonPushed, true);
            app.Annotationfilesrater2Button.Text = 'Annotation files rater 2';
            app.TextArea_2 = uitextarea(app.LeftPanel);
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.CorrectionDropDownLabel = uilabel(app.LeftPanel);
            app.CorrectionDropDownLabel.Text = 'Correction';
            app.CorrectionDropDown = uidropdown(app.LeftPanel);
            app.CorrectionDropDown.Items = {'include', 'exclude'};
            app.CorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionDropDownValueChanged, true);
            app.CorrectionDropDown.Value = 'include';
            app.ToleranceinsEditFieldLabel = uilabel(app.LeftPanel);
            app.ToleranceinsEditFieldLabel.Text = 'Tolerance (in s)';
            app.ToleranceinsEditField = uieditfield(app.LeftPanel, 'text');
            app.ToleranceinsEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceinsEditFieldValueChanged, true);
            app.ToleranceinsEditField.Value = '2';
            app.AnnotationoutputfolderButton = uibutton(app.LeftPanel, 'push');
            app.AnnotationoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotationoutputfolderButtonPushed, true);
            app.AnnotationoutputfolderButton.Text = 'Annotation output folder';
            app.OutputLabel = uilabel(app.LeftPanel);
            app.OutputLabel.Text = 'Output';
            app.AgreementtableButton = uibutton(app.LeftPanel, 'push');
            app.AgreementtableButton.ButtonPushedFcn = createCallbackFcn(app, @AgreementtableButtonPushed, true);
            app.AgreementtableButton.Text = 'Agreement table';
            app.SavefiguresCheckBox = uicheckbox(app.LeftPanel);
            app.SavefiguresCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavefiguresCheckBoxValueChanged, true);
            app.SavefiguresCheckBox.Text = 'Save figures';
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.CalculatefinalagreementButton = uibutton(app.LeftPanel, 'push');
            app.CalculatefinalagreementButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatefinalagreementButtonPushed, true);
            app.CalculatefinalagreementButton.Text = 'Calculate final agreement';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'filename'; 'positive agreement'; 'negative agreement'; 'prevalence index'; 'agreement trigger'; 'agreement type'; 'number FOG rater 1'; 'number FOG rater 2'; 'number FOG agreed'; 'number FOG disagreed rater 1'; 'number FOG disagreed rater 2'; 'duration FOG rater 1 (s)'; 'duration FOG rater 2 (s)'; 'duration FOG agreed (s)'; 'duration FOG disagreed rater 1 (s)'; 'duration FOG disagreed rater 2 (s)'; 'total duration (s)'};
            app.UITable.RowName = {''};
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'DONDERS_LOGO_RGB_small.png';
            app.Image3 = uiimage(app.RightPanel);
            app.Image3.ImageSource = 'RU-UMC-logo-screen.png';
            app.AgreementtableLabel = uilabel(app.RightPanel);
            app.AgreementtableLabel.Text = 'Agreement table';
            app.Image4 = uiimage(app.RightPanel);
            app.Image4.ImageSource = 'FOGtool_logo.png';
            app.Image5 = uiimage(app.RightPanel);
            app.Image5.ImageSource = 'MST_logo.png';
            % Show the figure after all components are created
            app.FOGtool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FOGtool)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FOGtool)
        end
    end
end